classdef smart_pillbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        LCDI2CAdressEditField          matlab.ui.control.EditField
        LCDI2CAdressEditFieldLabel     matlab.ui.control.Label
        GeneralSettingLabel            matlab.ui.control.Label
        OptionPanel                    matlab.ui.container.Panel
        UseLEDCheckBox                 matlab.ui.control.CheckBox
        UsebuzzerCheckBox              matlab.ui.control.CheckBox
        UseLCDCheckBox                 matlab.ui.control.CheckBox
        MonitoringPanel                matlab.ui.container.Panel
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ArduinoPanel                   matlab.ui.container.Panel
        DisconnectButton               matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        PreferenceTab                  matlab.ui.container.Tab
        PreferenceSettingLabel         matlab.ui.control.Label
        InformationPanel               matlab.ui.container.Panel
        DayssupplyofpillsEditField     matlab.ui.control.NumericEditField
        DayssupplyofpillsEditFieldLabel  matlab.ui.control.Label
        DosetimeEditField              matlab.ui.control.EditField
        DosetimeEditFieldLabel         matlab.ui.control.Label
        SettingPanel_2                 matlab.ui.container.Panel
        TimeallowanceminEditField      matlab.ui.control.NumericEditField
        TimeallowanceminLabel          matlab.ui.control.Label
        DataTab                        matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        ActualdosetimeTab              matlab.ui.container.Tab
        MaximumEditField_3Label        matlab.ui.control.Label
        AverageEditField_3Label        matlab.ui.control.Label
        MinimumEditFieldLabel          matlab.ui.control.Label
        MinimumEditField               matlab.ui.control.EditField
        MaximumEditField_1             matlab.ui.control.EditField
        RangeDropDown                  matlab.ui.control.DropDown
        RangeDropDownLabel             matlab.ui.control.Label
        AverageEditField_1             matlab.ui.control.EditField
        ActualDoseTimeAxes             matlab.ui.control.UIAxes
        DosedelaytimeTab               matlab.ui.container.Tab
        RangeDropDown_2                matlab.ui.control.DropDown
        RangeDropDown_2Label           matlab.ui.control.Label
        MaximumEditField_4Label        matlab.ui.control.Label
        MaximumEditField_2             matlab.ui.control.EditField
        AverageEditField_4Label        matlab.ui.control.Label
        AverageEditField_2             matlab.ui.control.EditField
        DoseDelayTimeAxes              matlab.ui.control.UIAxes
        AnalysisTab                    matlab.ui.container.Tab
        RangeDropDown_3                matlab.ui.control.DropDown
        RangeDropDown_3Label           matlab.ui.control.Label
        MaxdelaydayEditFieldLabel      matlab.ui.control.Label
        MaxdelaydayEditField           matlab.ui.control.EditField
        CorrectdoserateEditFieldLabel  matlab.ui.control.Label
        RecommenddosetimeEditFieldLabel  matlab.ui.control.Label
        CorrectdoserateEditField       matlab.ui.control.EditField
        RecommenddosetimeEditField     matlab.ui.control.EditField
        DoseRatioAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        s1  % 아두이노 저장 변수
        lcd % LCD 저장 변수

        TimerObj              % 센서 갱신 주기 저장 변수
        LCDHoldUntil = 0      % LCD 임시 메시지 유지 종료 시각
        IsRunning = false     % 앱 실행 여부 저장 변수(기본값: false)
        IsConnected = false   % Arduino 연결 여부 저장 변수
        LCDBacklightOn = true % LCD 백라이트 켜짐 여부 저장 변수(기본값: True)

        T                           % 복용 기록 저장 변수
        LogFile = "pillbox_log.csv" % 복용 기록 파일 이름: pillbox_log
        MonitorStartDate            % 미복용 기록 시작 날짜 저장 변수

        DoseTime              % 예정 복용 시간 저장 변수
        LastOpenState = false % 뚜껑 열림 여부 저장 변수(기본값: false)
        LastOpenTime          % 마지막 뚜껑 개봉 시각 저장 변수

        REED_PIN = 'D2'   % 리드 스위치 연결 아두이노 디지털 핀 번호
        BUZZER_PIN = 'D4' % 능동 부저 연결 아두이노 디지털 핀 번호
        RGB_R_PIN = 'D9'  % RGB LED 모듈 빨간색 채널 연결 아두이노 디지털 핀 번호
        RGB_G_PIN = 'D10' % RGB LED 모듈 초록색 채널 연결 아두이노 디지털 핀 번호
        RGB_B_PIN = 'D11' % RGB LED 모듈 파란색 채널 연결 아두이노 디지털 핀 번호
    end

    methods (Access = private)
        % ====================================================================================================
        % 모니터링 함수
        % ====================================================================================================

        % 약 보관함 모니터링 함수
        function checkPillbox(app)
            if ~app.IsRunning % 앱 실행 중이 아니면
                return;       % 함수 종료 후 호출 위치로 복귀
            end

            nowTime = datetime('now');                        % 현재 시각 저장
            today = dateshift(nowTime, 'start', 'day');       % 현재 날짜의 시각 저장
            todayText = string(datestr(today, 'yyyy-mm-dd')); % 오늘 날짜 yyyy-mm-dd 문자열로 저장
            scheduledTime = today + app.DoseTime;             % 오늘 예정 복용 시각 계산(오늘 날짜 + 예정 복용 시각)
            allowance = app.TimeallowanceminEditField.Value;  % 정시 복용 인정 시간 범위 저장

            app.addMissedRecords(); % 미복용 기록 저장 함수 호출

            % 오늘 복용 기록 존재 여부 저장(예정 날짜가 오늘 & 복용 인정 기록인 행이 존재하면 참)
            alreadyTaken = any(string(app.T.ScheduleDate) == todayText & str2double(string(app.T.ValidDose)) == 1);

            reedValue = readDigitalPin(app.s1, app.REED_PIN); % 리드 스위치가 연결 디지털 핀의 값 읽기
            isOpen = reedValue == 1;                          % 리드 스위치 값이 열림 상태 여부(true/false)

            % 현재: 뚜껑 열림 상태 & 이전: 뚜껑 닫힘 상태 & 현재 시각-마지막 뚜껑 열림 시각 > 1초이면
            if isOpen && ~app.LastOpenState && seconds(nowTime - app.LastOpenTime) > 1
                app.LastOpenTime = nowTime; % 마지막 뚜껑 열림 시각을 현재 시각으로 갱신

                if ~alreadyTaken                                          % 오늘 복용 기록이 존재하지 않으면
                    app.addDoseRecord(nowTime, scheduledTime, allowance); % 복용 기록 추가
                    alreadyTaken = true;                                  % 오늘 복용 기록 존재 여부 참으로 갱신

                    app.setBuzzer(0);                                     % 능동 부저 끄기

                    stock = app.getStock();                               % 잔여 약 분량 계산 후 저장
                    app.showStockLED(stock);                              % 잔여 약 분량 표시 RGB LED 점등

                    app.updateGraphs();                                   % 복용 기록 그래프 및 통계 갱신

                    app.showLCDhold('Dose Recorded', ['Stock: ' num2str(stock)], 3); % LCD에 기록 알림 메세지 3초간 출력
                end
            end

            app.LastOpenState = isOpen; % 이전 뚜껑 열림 여부를 현재 상태로 갱신

            stock = app.getStock();     % 현재 잔여 약 분량 계산 후 저장

            if alreadyTaken                                           % 오늘 복용 기록이 존재하면
                app.setBuzzer(0);                                     % 능동 부저 끄기
                app.showStockLED(stock);                              % 잔여 약 분량 표시 RGB LED 점등
            else                                                      % 오늘 복용 기록이 존재하지 않으면
                delayFromSchedule = minutes(nowTime - scheduledTime); % 복용 시간 격차 분 단위 계산(현재 시각 - 예정 복용 시각)
                app.showWarning(delayFromSchedule, stock);            % 복용 지연 시간 및 잔여 약 분량 RGB LED 및 능동 부저 경고
            end

            app.updateRunningLCD(nowTime, scheduledTime, alreadyTaken, stock); % LCD에 예정 복용 시간 격차 지속적으로 출력
        end

        % ====================================================================================================
        % 기록 함수
        % ====================================================================================================

        % 복용 기록 추가 함수
        function addDoseRecord(app, openTime, scheduledTime, allowance)
            delayMin = minutes(openTime - scheduledTime); % 복용 시간 격차 분 단위 계산(뚜껑 열림 시간 - 예정 복용 시간)

            if abs(delayMin) <= allowance % 지연 시간 <= 복용 인정 시간 범위라면
                status = "OnTime";        % 정시 복용으로 저장
            else                          % 지연 시간 > 복용 인정 시간 범위라면
                status = "Delayed";       % 지연 복용으로 저장
            end

            % 잔여 약 분량 계산(초기 약 분량 - 복용 횟수 - 방금 복용 1회)(최솟값: 0)
            stock = max(app.DayssupplyofpillsEditField.Value - sum(app.T.ValidDose == 1) - 1, 0);

            % 복용 기록 테이블의 새 행에 복용 기록 저장
            newRow = table( ...
                string(datestr(dateshift(openTime, 'start', 'day'), 'yyyy-mm-dd')), ... % 복용 날짜 yyyy-mm-dd 형식 문자열로 저장
                string(datestr(scheduledTime, 'yyyy-mm-dd HH:MM:SS')), ...              % 예정 복용 시각 문자열로 저장
                string(datestr(openTime, 'yyyy-mm-dd HH:MM:SS')), ...                   % 실제 개봉 시각 문자열로 저장
                delayMin, ...                                                           % 복용 지연 시간 새 행에 저장
                status, ...                                                             % 복용 상태 새 행에 저장
                string(app.getDayName(openTime)), ...                                   % 복용 요일 새 행에 저장
                1, ...                                                                  % 유효 복용 기록 값 1로 저장
                stock, ...                                                              % 잔여 약 분량 새 행에 저장
                'VariableNames', app.T.Properties.VariableNames);                       % 기존 복용 기록과 같은 변수 이름으로 행 저장

            app.T = [app.T; newRow];        % 기존 복용 기록 아래에 새 행 추가
            writetable(app.T, app.LogFile); % 갱신된 복용 기록 테이블 CSV 파일로 저장
        end

        % 미복용 기록 추가 함수
        function addMissedRecords(app)
            today = dateshift(datetime('now'), 'start', 'day'); % 오늘 날짜의 시작 시각 계산

            d = app.MonitorStartDate; % 시작 날짜부터 작동하기 위해 날짜 변수 설정

            while d < today                                         % 오늘 이전 날짜까지 반복
                dText = string(datestr(d, 'yyyy-mm-dd'));           % 작동 중인 날짜 yyyy-mm-dd 형식 문자열로 변환
                alreadyRecorded = any(string(app.T.ScheduleDate) == dText); % 해당 날짜 기록 존재 여부 확인

                if ~alreadyRecorded                   % 해당 날짜에 기록 미존재 시 미복용 기록 추가
                    scheduledTime = d + app.DoseTime; % 해당 날짜의 예정 복용 시각 계산
                    stock = app.getStock();           % 현재 잔여 약 분량 계산

                    newRow = table( ...                                            % 복용 기록 새 행에 저장
                        dText, ...                                                 % 미복용 날짜 새 행에 저장
                        string(datestr(scheduledTime, 'yyyy-mm-dd HH:MM:SS')), ... % 예정 복용 시각 문자열로 저장
                        "", ...                                                    % 개봉 시간이 없으므로 빈 값으로 저장
                        NaN, ...                                                   % 지연 시간을 계산할 수 없으므로 NaN으로 저장
                        "Missed", ...                                              % 복용 상태 미복용으로 저장
                        string(app.getDayName(d)), ...                             % 미복용 날짜의 요일 저장
                        0, ...                                                     % 유효 복용 기록이 아니므로 0으로 저장
                        stock, ...                                                 % 잔여 약 분량 새 행에 저장
                        'VariableNames', app.T.Properties.VariableNames);          % 기존 복용 기록과 같은 변수 이름으로 행 저장

                    app.T = [app.T; newRow];        % 기존 복용 기록 아래에 새 행 추가
                    writetable(app.T, app.LogFile); % 갱신된 복용 기록 테이블 CSV 파일로 저장
                    app.updateGraphs();             % 미복용 기록을 그래프 및 통계에 즉시 반영
                end

                d = d + days(1); % 다음 날짜로 이동
            end
        end

        % 복용 기록 CSV 파일 불러오기 및 생성 함수
        function T = loadLog(app)
            % 복용 기록에 필요한 변수 이름 설정
            requiredNames = {'ScheduleDate', 'ScheduledDateTime', 'OpenDateTime', ...
                'DelayMinutes', 'Status', 'WeekdayName', 'ValidDose', 'StockRemaining'};


            if isfile(app.LogFile)                                % 복용 기록 CSV 파일이 존재하면
                T = readtable(app.LogFile, 'TextType', 'string'); % 기존 기록 불러오기

                if all(ismember(requiredNames, T.Properties.VariableNames))
                    T = T(:, requiredNames); % 필요한 열만 지정된 순서로 정렬
                    return;
                end
            end

            % CSV 파일이 없으면 빈 복용 기록 테이블 생성
            ScheduleDate      = strings(0, 1);
            ScheduledDateTime = strings(0, 1);
            OpenDateTime      = strings(0, 1);
            DelayMinutes      = zeros(0, 1);
            Status            = strings(0, 1);
            WeekdayName       = strings(0, 1);
            ValidDose         = zeros(0, 1);
            StockRemaining    = zeros(0, 1);

            % 복용 기록 테이블의 변수 이름 목록 설정
            T = table(ScheduleDate, ScheduledDateTime, OpenDateTime, DelayMinutes, ...
                Status, WeekdayName, ValidDose, StockRemaining);

            writetable(T, app.LogFile); % 새 빈 복용 기록 테이블을 CSV 파일로 저장
        end

        % 잔여 약 분량 계산 함수
        function stock = getStock(app)
            initialStock = app.DayssupplyofpillsEditField.Value; % 초기 약 분량 값 저장
            takenCount = sum(app.T.ValidDose == 1);              % 복용 횟수 합산
            stock = max(initialStock - takenCount, 0);           % 잔여 약 분량 최솟값 0으로 제한(초기 약 분량 - 복용 횟수)
        end

        % ====================================================================================================
        % 그래프 & 통계 함수
        % ====================================================================================================

        % 그래프 & 통계 갱신 함수
        function updateGraphs(app)
            T2 = app.filteredData(); % 선택된 기간의 복용 기록 불러오기

            cla(app.ActualDoseTimeAxes); % 실제 복용 시간 그래프 영역 초기화
            cla(app.DoseDelayTimeAxes);  % 복용 지연 시간 그래프 영역 초기화
            cla(app.DoseRatioAxes);      % 복용 비율 그래프 영역 초기화

            if height(T2) == 0                                    % 선택된 기간의 복용 기록이 없으면
                app.MinimumEditField.Value = 'No data';           % 실제 복용 시간 최솟값 초기화
                app.AverageEditField_1.Value = 'No data';         % 실제 복용 시간 평균값 초기화
                app.MaximumEditField_1.Value = 'No data';         % 실제 복용 시간 최댓값 초기화
                app.AverageEditField_2.Value = 'No data';         % 지연 시간 평균값 초기화
                app.MaximumEditField_2.Value = 'No data';         % 지연 시간 최댓값 초기화
                app.RecommenddosetimeEditField.Value = 'No data'; % 추천 복용 시간 값 초기화
                app.CorrectdoserateEditField.Value = '0.0%';      % 정시 복용률 값 초기화
                app.MaxdelaydayEditField.Value = 'No data';       % 최대 지연 요일 값 초기화
                return;                                           % 함수 종료
            end

            valid = T2.ValidDose == 1 & T2.OpenDateTime ~= ""; % 복용 및 뚜껑 열림 시각 기록이 있는 행 선택

            if any(valid)                                            % 복용 기록이 존재하면
                                                                     % 뚜껑 열림 시각 문자열을 datetime 형식으로 변환
                openTime = datetime(T2.OpenDateTime(valid), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                openDate = dateshift(openTime, 'start', 'day');      % 뚜껑 열림 시각(복용) 날짜 단위로 정리
                openMinute = hour(openTime) * 60 + minute(openTime); % 뚜껑 열림 시각(복용) 자정 이후 경과한 분 값으로 계산

                plot(app.ActualDoseTimeAxes, openDate, openMinute, '-o'); % 날짜별 뚜껑 열림 시각(복용) 선 그래프로 출력
                title(app.ActualDoseTimeAxes, 'Actual Dose Time');        % 뚜껑 열림 시각(복용) 그래프 제목 설정
                xlabel(app.ActualDoseTimeAxes, 'Date');                   % 뚜껑 열림 시각(복용) 그래프 x축 이름 설정
                ylabel(app.ActualDoseTimeAxes, 'Time(HH:MM)');                   % 뚜껑 열림 시각(복용) 그래프 y축 이름 설정
                grid(app.ActualDoseTimeAxes, 'on');                       % 뚜껑 열림 시각(복용) 그래프 격자 표시
                app.ActualDoseTimeAxes.YTick = 0:120:1440;                % y축 눈금 2시간 간격 분 값으로 설정
                                                                          % y축 눈금 값 HH:MM 형식 문자로 변환 표시
                app.ActualDoseTimeAxes.YTickLabel = arrayfun(@(x) app.minuteToText(x), 0:120:1440, 'UniformOutput', false);

                delay = T2.DelayMinutes(valid);                                        % 복용 기록의 지연 시간 값 추출
                date2 = datetime(T2.ScheduleDate(valid), 'InputFormat', 'yyyy-MM-dd'); % 예정 복용 날짜 문자열 datetime 형식으로 변환

                plot(app.DoseDelayTimeAxes, date2, delay, '-o');                       % 날짜별 복용 지연 시간 선 그래프로 출력
                yline(app.DoseDelayTimeAxes, 0, '--');                                 % 지연 시간 0인 기준선 그래프에 추가
                title(app.DoseDelayTimeAxes, 'Dose Delay Time');                       % 복용 지연 시간 그래프 제목 설정
                xlabel(app.DoseDelayTimeAxes, 'Date');                                 % 복용 지연 시간 그래프 x축 이름 설정
                ylabel(app.DoseDelayTimeAxes, 'Delay(min)');                            % 복용 지연 시간 그래프 y축 이름 설정
                grid(app.DoseDelayTimeAxes, 'on');                                     % 복용 지연 시간 그래프 격자 표시

                app.MinimumEditField.Value = app.minuteToText(min(openMinute));         % 가장 이른 복용 시각 값 표시
                app.AverageEditField_1.Value = app.minuteToText(mean(openMinute));      % 평균 복용 시각 값 표시
                app.MaximumEditField_1.Value = app.minuteToText(max(openMinute));       % 가장 늦은 복용 시각 값 표시
                app.AverageEditField_2.Value = app.delayToText(mean(delay, 'omitnan')); % 평균 복용 지연 시간 값 표시(결측값 제외)
                app.MaximumEditField_2.Value = app.delayToText(max(delay));             % 최대 복용 지연 시간 값 표시

                recMinute = app.recommendMinute(openMinute);                        % 추천 복용 시간 분 단위로 계산
                app.RecommenddosetimeEditField.Value = app.minuteToText(recMinute); % 복용 시간 기록의 중앙값(추천 시간) 표시

                [~, idx] = max(delay);                                                     % 최대 지연 시간 값 위치 찾기
                worstDayNumber = weekday(openTime(idx));                                   % 최대 지연 요일 번호 계산
                app.MaxdelaydayEditField.Value = app.weekdayNumberToText(worstDayNumber); % 최대 지연 요일 번호 표시
            else % 복용 기록이 존재하지 않으면
                app.MinimumEditField.Value = 'No data';           % 실제 복용 시간 최솟값 데이터 없음으로 표시
                app.AverageEditField_1.Value = 'No data';         % 실제 복용 시간 평균값 데이터 없음으로 표시
                app.MaximumEditField_1.Value = 'No data';         % 실제 복용 시간 최댓값 데이터 없음으로 표시
                app.AverageEditField_2.Value = 'No data';         % 평균 지연 시간 데이터 없음으로 표시
                app.MaximumEditField_2.Value = 'No data';         % 최대 지연 시간 데이터 없음으로 표시
                app.RecommenddosetimeEditField.Value = 'No data'; % 추천 복용 시간 데이터 없음으로 표시
                app.MaxdelaydayEditField.Value = 'No data';       % 최대 지연 요일 데이터 없음으로 표시
            end

            onTimeCount = sum(T2.Status == "OnTime");              % 정시 복용 기록 횟수 계산
            delayedCount = sum(T2.Status == "Delayed");            % 지연 복용 기록 횟수 계산
            missedCount = sum(T2.Status == "Missed");              % 미복용 기록 횟수 계산
            totalCount = onTimeCount + delayedCount + missedCount; % 전체 기록 횟수 계산

            if totalCount > 0                                                                           % 복용 기록이 있으면
                pie(app.DoseRatioAxes, [onTimeCount delayedCount missedCount]);                         % 정시·지연·미복용 비율 원형 그래프로 표시
                legend(app.DoseRatioAxes, {'On-time', 'Delayed', 'Missed'}, 'Location', 'bestoutside'); % 범례 그래프 바깥쪽에 표시
                title(app.DoseRatioAxes, 'Dose Ratio');                                                 % 복용 비율 그래프 제목 설정
                rateValue = 100 * onTimeCount / totalCount;                                             % 정시 복용률 백분율 계산
                app.CorrectdoserateEditField.Value = app.rateToText(rateValue);                         % 정시 복용률 문자열로 표시
            else
                app.CorrectdoserateEditField.Value = '0.0%';
            end
        end

        % 데이터 필터링 함수
        function T2 = filteredData(app)
            T2 = app.T; % 전체 복용 기록 테이블 임시 테이블로 복사

            if height(T2) == 0 % 복용 기록 테이블이 비어 있으면
                return;        % 함수 중지
            end

            mode = string(app.RangeDropDown.Value);                             % 드롭다운 선택 필터 조건 문자열로 저장
            dateValue = datetime(T2.ScheduleDate, 'InputFormat', 'yyyy-MM-dd'); % 복용 예정 날짜 datetime 값으로 변환
            today = dateshift(datetime('now'), 'start', 'day');                 % 오늘 날짜의 시작 시각 계산

            % 선택 기간 별 데이터 남기기
            if mode == "Last 7 days"
                T2 = T2(dateValue >= today - days(6), :);
            elseif mode == "Last 14 days"
                T2 = T2(dateValue >= today - days(13), :);
            elseif mode == "Last 30 days"
                T2 = T2(dateValue >= today - days(29), :);
            elseif mode == "Weekdays"
                T2 = T2(~(T2.WeekdayName == "Saturday" | T2.WeekdayName == "Sunday"), :);
            elseif mode == "Weekend"
                T2 = T2(T2.WeekdayName == "Saturday" | T2.WeekdayName == "Sunday", :);
            elseif mode ~= "All"
                T2 = T2(T2.WeekdayName == mode, :);
            end
        end

        % 추천 복용 시간 계산 함수
        function rec = recommendMinute(app, minuteList)
            x = sort(minuteList(:)); % 복용 시간 분 값 세로 벡터로 변환 및 오름차순 정렬

            if length(x) >= 5   % 복용 기록이 5개 이상이면
                x = x(2:end-1); % 가장 빠른 값과 가장 늦은 값 제외
            end

            rec = round(median(x)); % 남은 복용 시간 값의 중앙값 반올림 및 추천 시간으로 저장
        end 

        % ====================================================================================================
        % RGB LED & 능동 부저 함수
        % ====================================================================================================

        % RGB LED & 능동 부저 제어 함수
        function showWarning(app, delayMin, stock)
            if stock <= 0                 % 잔여 약 분량이 0개 이하이면
                app.blinkRGB(1, 0, 0, 2); % RGB LED 빨간색으로 점멸
                app.setBuzzer(0);         % 능동 부저 끄기
            elseif delayMin >= 60         % 예정 복용 시간을 60분 이상 지났으면
                app.setRGB(1, 0, 0);      % RGB LED 빨간색으로 점등
                app.setBuzzer(1);         % 능동 부저 켜기
            elseif delayMin >= 30         % 예정 복용 시간을 30분 이상 지났으면
                app.blinkRGB(1, 0, 0, 2); % RGB LED 빨간색으로 점멸
                app.setBuzzer(0);         % 30분 지연 경고에서는 부저를 끔
            elseif delayMin >= 10         % 예정 복용 시간보다 10분 이상 지났는지 확인
                app.blinkRGB(1, 1, 0, 1); % RGB LED 노란색으로 점멸
                app.setBuzzer(0);         % 능동 부저 끄기
            else                          % 경고 조건에 해당하지 않으면
                app.showStockLED(stock);  % RGB LED 잔여 약 개수에 맞는 색상으로 점등
                app.setBuzzer(0);         % 능동 부저 끄기
            end
        end

        % RGB LED 색상 표시 함수
        function showStockLED(app, stock)
            if stock <= 0                 % 남은 약이 0개 이하이면
                app.blinkRGB(1, 0, 0, 2); % 빨간색 LED 점멸
            elseif stock <= 1             % 남은 약이 1개 이하이면
                app.setRGB(1, 0, 0);      % 빨간색 LED 점등
            elseif stock <= 3             % 남은 약이 3개 이하이면
                app.setRGB(1, 1, 0);      % 노란색 LED 점등
            else                          % 약이 4개 이상이라면
                app.setRGB(0, 1, 0);      % 초록색 LED 점등
            end
        end

        % RGB LED 점멸 함수
        function blinkRGB(app, r, g, b, speed)
            phase = mod(floor(posixtime(datetime('now')) * speed), 2);

            if phase == 0
                app.setRGB(r, g, b);
            else
                app.setRGB(0, 0, 0);
            end
        end

        % RGB LED 색 지정 함수
        function setRGB(app, r, g, b)
            if ~app.UseLEDCheckBox.Value % LED 사용 옵션이 꺼져 있으면
                r = 0; % 빨간색 채널 출력 0으로 설정
                g = 0; % 초록색 채널 출력 0으로 설정
                b = 0; % 파란색 채널 출력 0으로 설정
            end

            writeDigitalPin(app.s1, app.RGB_R_PIN, r); % 빨간색 채널에 디지털 출력값 전달
            writeDigitalPin(app.s1, app.RGB_G_PIN, g); % 초록색 채널에 디지털 출력값 전달
            writeDigitalPin(app.s1, app.RGB_B_PIN, b); % 파란색 채널에 디지털 출력값 전달
        end

        % 능동 부저 작동 함수
        function setBuzzer(app, state)
            if ~app.UsebuzzerCheckBox.Value % 능동 부저 사용 옵션이 꺼져 있으면
                state = 0;                  % 출력값 0으로 설정
            end

            writeDigitalPin(app.s1, app.BUZZER_PIN, state); % 부저 핀에 디지털 출력값 입력
        end

        % ====================================================================================================
        % LCD 함수
        % ====================================================================================================

        % LCD 초기화 함수
        function lcdInit(app)
            pause(0.1); % LCD 전원 및 I2C 통신 안정화 시간 확보

            app.lcdWrite4(hex2dec('03'), 0); % LCD 초기화를 위한 첫 번째 8비트 모드 신호 전송
            pause(0.01);                     % 첫 번째 초기화 신호 처리 시간 확보
            app.lcdWrite4(hex2dec('03'), 0); % LCD 초기화를 위한 두 번째 8비트 모드 신호 전송
            pause(0.01);                     % 두 번째 초기화 신호 처리 시간 확보
            app.lcdWrite4(hex2dec('03'), 0); % LCD 초기화를 위한 세 번째 8비트 모드 신호 전송
            pause(0.01);                     % 세 번째 초기화 신호 처리 시간 확보
            app.lcdWrite4(hex2dec('02'), 0); % LCD를 4비트 전송 모드로 전환하는 신호 전송
            pause(0.01);                     % 4비트 모드 전환 신호 처리 시간 확보

            app.lcdWriteByte(uint8(hex2dec('28')), 0); % LCD 4비트·2줄·5x8 글꼴 표시 방식으로 설정
            app.lcdWriteByte(uint8(hex2dec('0C')), 0); % LCD 화면 켜기 및 커서 표시 끄기
            app.lcdWriteByte(uint8(hex2dec('06')), 0); % 문자 출력 후 다음 위치로 이동하도록 설정
            app.lcdWriteByte(uint8(hex2dec('01')), 0); % LCD의 기존 문자 삭제
            pause(0.02);                               % 기존 문자 삭제 시간 확보
        end

        % LCD 출력 함수
        function lcdPrint(app, lineNumber, textValue)
            textValue = char(string(textValue)); % 입력된 값 LCD 출력용 문자 배열로 변환

            if length(textValue) > 16        % 문자 길이 > 16(LCD 한 줄 표시 가능 길이)이면
                textValue = textValue(1:16); % 16자까지만 저장
            end

            textValue = [textValue repmat(' ', 1, 16 - length(textValue))]; % 부족한 글자 수만큼 공백 추가

            if lineNumber == 1                             % 출력 줄이 첫 번째 줄이면
                app.lcdWriteByte(uint8(hex2dec('80')), 0); % LCD 커서 첫 번째 줄 시작 위치로 이동
            else                                           % 출력 줄이 첫 번째 줄이 아니면
                app.lcdWriteByte(uint8(hex2dec('C0')), 0); % LCD 커서 두 번째 줄 시작 위치로 이동
            end

            for i = 1:16                                  % 16회(LCD 한 줄의 문자 개수) 반복
                app.lcdWriteByte(uint8(textValue(i)), 1); % i번째 문자 데이터 모드로 LCD에 전송
            end
        end

        % LCD 두 줄 출력 함수
        function lcdShow(app, line1, line2)
            app.lcdPrint(1, line1); % 첫 번째 줄에 line1 내용 출력
            app.lcdPrint(2, line2); % 두 번째 줄에 line2 내용 출력
        end

        % 데이터 LCD 전송 함수(8비트 → 4비트 + 4비트)
        function lcdWriteByte(app, data, rs)
            app.lcdWrite4(bitshift(data, -4), rs);          % 상위 4비트 데이터 LCD에 전송
            app.lcdWrite4(bitand(data, hex2dec('0F')), rs); % 하위 4비트 데이터 LCD에 전송
        end

        % 데이터 LCD I2C 모듈 전송 함수
        function lcdWrite4(app, nibble, rs)
            if app.LCDBacklightOn                 % LCD 백라이트가 켜져 있으면
                backlight = uint8(hex2dec('08')); % 백라이트 켜짐 비트 값 저장
            else                                  % LCD 백라이트가 꺼져 있으면
                backlight = uint8(0);             % 백라이트 꺼짐 비트 값 저장
            end

            enable = uint8(hex2dec('04'));                       % LCD Enable 비트 값 저장
            data = bitor(bitshift(uint8(nibble), 4), backlight); % 4비트 데이터 값 및 백라이트 비트 값 결합

            if rs == 1                        % RS(Register Select) 값이 문자 데이터이면
                data = bitor(data, uint8(1)); % RS 비트 추가(데이터 모드로 설정)
            end

            write(app.lcd, uint8(bitor(data, enable))); % Enable 비트 켠 상태의 데이터 LCD에 전송
            pause(0.001);                               % LCD Enable ON 상태 인식 시간 확보
            write(app.lcd, uint8(data));                % Enable 비트 끈 상태의 데이터 LCD에 전송
            pause(0.001);                               % LCD Enable OFF 상태 인식 시간 확보
        end

        % LCD 임시 메시지 출력 함수
        function showLCDhold(app, line1, line2, secondsToShow)
            if ~app.UseLCDCheckBox.Value % LCD 사용 옵션이 꺼져 있으면
                return;                  % 함수 종료
            end

            app.LCDHoldUntil = now + secondsToShow / 86400; % 현재 시각 기준으로 임시 메시지 유지 종료 시각 저장
            app.setLCDBacklight(true);                      % LCD 백라이트 켜기
            app.lcdShow(line1, line2);                      % LCD에 임시 메시지 두 줄로 출력
        end

        % LCD 임시 메시지 출력 및 삭제 함수
        function showLCDflash(app, line1, line2, secondsToShow)
            if ~app.UseLCDCheckBox.Value % LCD 사용 옵션이 꺼져 있으면
                return; % 함수 종료
            end

            app.setLCDBacklight(true); % LCD 백라이트 켜기
            app.lcdShow(line1, line2); % LCD에 임시 메시지 두 줄로 출력
            pause(secondsToShow);      % 지정된 시간 동안 현재 메시지 유지
            app.lcdShow('', '');       % LCD 빈 문자열로 덮어쓰기
        end

        % LCD 갱신 함수
        function updateRunningLCD(app, nowTime, scheduledTime, alreadyTaken, stock)
            if ~app.UseLCDCheckBox.Value % LCD 사용 옵션이 꺼져 있으면
                return;                  % 함수 종료
            end

            if now < app.LCDHoldUntil % 임시 메시지 유지 시간이 남아 있으면
                return;               % 함수 종료(LCD 갱신 건너뛰기)
            end

            if ~app.LCDBacklightOn         % LCD 백라이트가 꺼져 있으면
                app.setLCDBacklight(true); % LCD 백라이트 켜기
            end

            if alreadyTaken                                                               % 오늘 복용 기록이 있으면
                targetTime = dateshift(nowTime, 'start', 'day') + days(1) + app.DoseTime; % 내일 예정 복용 시각 계산
                diffMinRaw = minutes(targetTime - nowTime);                               % 다음 복용까지 남은 시간 분 단위로 계산
                line1 = 'Next dose in';                                                   % LCD 첫 번째 줄의 다음 복용 안내 문구 저장
                diffMin = ceil(diffMinRaw);                                               % 남은 시간 올림 처리(정수 분 값으로 저장)
            else                                                                          % 오늘 복용 기록이 없으면
                diffMinRaw = minutes(scheduledTime - nowTime);                            % 오늘 예정 복용 시각과 시간 차이 분 단위로 계산

                if diffMinRaw >= 0                                                        % 예정 복용 시간이 지나지 않았으면
                    line1 = 'Time remaining';                                             % LCD 첫 번째 줄의 남은 시간 문구 저장
                    diffMin = ceil(diffMinRaw);                                           % 남은 시간 올림 처리(정수 분 값으로 저장)
                else                                                                      % 예정 복용 시간이 지났으면
                    line1 = 'Time passed';                                                % LCD 첫 번째 줄의 남은 시간 문구 저장
                    diffMin = floor(abs(diffMinRaw));                                     % 지난 시간의 절댓값 내림 처리(정수 분 값으로 저장)
                end
            end

            h = floor(diffMin / 60); % 분 단위 시간 → 시간 단위 값 변환
            m = mod(diffMin, 60);    % 나머지 분 계산

            app.lcdShow(line1, sprintf('%02dh %02dm S:%d', h, m, stock)); % LCD 두 번째 줄에 시간·분·잔여 약 분량 표시
        end

        % LCD 백라이트 작동 함수
        function setLCDBacklight(app, state)
            app.LCDBacklightOn = state; % 현재 LCD 백라이트 상태 저장

            if state                                  % 백라이트를 켜야 하면
                write(app.lcd, uint8(hex2dec('08'))); % I2C 모듈에 백라이트 켜기 값 전송
            else                                      % 백라이트를 꺼야 하면
                write(app.lcd, uint8(0));             % I2C 모듈에 백라이트 끄기 값 전송
            end

            pause(0.001); % LCD 백라이트 명령 처리 시간 확보
        end

        % ====================================================================================================
        % 기능 함수
        % ====================================================================================================

        % 구성요소 활성화 함수
        function setControls(app, mode)
            if mode == "busy"
                app.ConnectButton.Enable = "off";
                app.DisconnectButton.Enable = "off";
                app.StartButton.Enable = "off";
                app.StopButton.Enable = "off";
                app.UseLEDCheckBox.Enable = "off";
                app.UseLCDCheckBox.Enable = "off";
                app.UsebuzzerCheckBox.Enable = "off";

            elseif mode == "disconnected"
                app.ConnectButton.Enable = "on";
                app.DisconnectButton.Enable = "off";
                app.StartButton.Enable = "off";
                app.StopButton.Enable = "off";
                app.UseLEDCheckBox.Enable = "off";
                app.UseLCDCheckBox.Enable = "off";
                app.UsebuzzerCheckBox.Enable = "off";

            elseif mode == "connected"
                app.ConnectButton.Enable = "off";
                app.DisconnectButton.Enable = "on";
                app.StartButton.Enable = "on";
                app.StopButton.Enable = "off";
                app.UseLEDCheckBox.Enable = "on";
                app.UseLCDCheckBox.Enable = "on";
                app.UsebuzzerCheckBox.Enable = "on";

            elseif mode == "running"
                app.ConnectButton.Enable = "off";
                app.DisconnectButton.Enable = "off";
                app.StartButton.Enable = "off";
                app.StopButton.Enable = "on";
                app.UseLEDCheckBox.Enable = "on";
                app.UseLCDCheckBox.Enable = "on";
                app.UsebuzzerCheckBox.Enable = "on";
            end
        end

        % 앱 중지 함수
        function stopApp(app)
            if app.IsRunning          % 모니터링 중이면
                stop(app.TimerObj);   % 실행 중인 타이머 정지
                delete(app.TimerObj); % 정지한 타이머 객체 삭제
                app.TimerObj = [];    % 타이머 저장 변수 빈 값으로 초기화
            end

            app.IsRunning = false; % 앱 실행 상태 중지 상태로 변경

            if app.IsConnected       % 아두이노가 연결되어 있으면
                app.setBuzzer(0);    % 능동 부저 끄기
                app.setRGB(0, 0, 0); % RGB LED 끄기
            end
        end

        % 문자열 변환 함수(HH:MM 형식 문자열 → duration 값)
        function d = textToDuration(app, textValue)
            textValue = strtrim(char(textValue));

            % HH:MM 형식 및 00:00~23:59 범위 확인
            isValid = ~isempty(regexp(textValue, ...
                '^(?:[01]?\d|2[0-3]):[0-5]\d$', 'once'));

            if ~isValid
                textValue = '08:00';
                app.DosetimeEditField.Value = '08:00';
            end

            value = sscanf(textValue, '%d:%d');

            d = hours(value(1)) + minutes(value(2));
        end

        % 요일 계산 함수
        function dayName = getDayName(app, timeValue)
            names = ["Sunday", "Monday", "Tuesday", "Wednesday", "Thursday", "Friday", "Saturday"]; % 요일 번호에 대응되는 영어 요일 이름 배열 생성
            dayName = names(weekday(timeValue));                                                    % datetime 값의 요일 번호에 맞는 요일 이름 반환
        end

        % 시간 변환 함수(분 단위 시간 값 → HH:MM 형식 문자열)
        function textValue = minuteToText(app, minuteValue)
            minuteValue = mod(round(minuteValue), 1440); % 분 값을 반올림 및 하루 범위 안으로 조정
            h = floor(minuteValue / 60);                 % 분 값에서 시간 부분 계산
            m = mod(minuteValue, 60);                    % 분 값에서 나머지 분 부분 계산
            textValue = sprintf('%02d:%02d', h, m);      % 시간, 분 두 자리 HH:MM 문자열로 변환
        end

        % 지연 시간 변환 함수
        function textValue = delayToText(app, delayValue)
            if isnan(delayValue)       % 지연 시간 값이 없으면
                textValue = 'No data'; % 데이터 없음으로 표시
                return;                % 함수 종료
            end

            delayValue = round(delayValue); % 지연 시간 값 정수 분으로 반올림

            if delayValue > 0                                         % 예정 복용 시간보다 늦었으면
                textValue = sprintf('%d min late', delayValue);       % 지연 시간 문자열 생성
            elseif delayValue < 0                                     % 예정 복용 시간보다 빨랐으면
                textValue = sprintf('%d min early', abs(delayValue)); % 이른 복용 시간 문자열 생성
            else                                                      % 예정 복용 시간과 같으면
                textValue = 'On time';                                % 정시 복용으로 표시
            end
        end

        % 정시 복용률 변환 함수
        function textValue = rateToText(app, rateValue)
            if isnan(rateValue)                           % 복용률 값이 없으면
                textValue = '0.0%';                       % 0.0%로 표시
            else                                          % 복용률 값이 있으면
                textValue = sprintf('%.1f%%', rateValue); % 소수점 한 자리 백분율 문자열 생성
            end
        end

        % 요일 변환 함수
        function textValue = weekdayNumberToText(app, dayNumber)
            names = {'Sunday', 'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday'}; % 요일 이름 목록

            if isnan(dayNumber) || dayNumber < 1 || dayNumber > 7 % 요일 번호가 비정상이면
                textValue = 'No data';                            % 데이터 없음으로 표시
            else                                                  % 요일 번호가 정상이면
                textValue = names{dayNumber};                     % 요일 번호에 맞는 요일 이름 반환
            end
        end

        % 날짜 드롭다운 동기화 함수
        function syncRangeDropDowns(app, selectedRange)
            app.RangeDropDown.Value = selectedRange;   % 실제 복용 시간 탭 드롭다운 값 갱신
            app.RangeDropDown_2.Value = selectedRange; % 복용 지연 시간 탭 드롭다운 값 갱신
            app.RangeDropDown_3.Value = selectedRange; % 복용 비율 탭 드롭다운 값 갱신

            app.updateGraphs(); % 선택된 기간 기준으로 모든 그래프 및 통계 갱신
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MonitorStartDate = dateshift(datetime('now'), 'start', 'day'); % 모니터링 시작 날짜 오늘 시각으로 설정
            app.LastOpenTime = datetime(2000, 1, 1); % 마지막 뚜껑 열림 시각 2000, 1, 1로 초기화

            app.DosetimeEditField.Value = '08:00';     % 예정 복용 시간 기본값 08:00로 설정
            app.DayssupplyofpillsEditField.Value = 30; % 잔여 약 분량 기본값 30으로 설정
            app.TimeallowanceminEditField.Value = 30;  % 정시 복용 인정 시간 범위 기본값 30으로 설정
            app.LCDI2CAdressEditField.Value = '0x27'; % LCD I2C 주소 기본값 0x27로 설정

            app.UseLEDCheckBox.Value = true;    % LED 사용 옵션 기본값 켬으로 설정
            app.UsebuzzerCheckBox.Value = true; % 능동 부저 사용 옵션 기본값 켬으로 설정
            app.UseLCDCheckBox.Value = true;    % LCD 사용 옵션 기본값 켬으로 설정

            % 날짜 드롭다운 선택 목록 저장
            rangeItems = {'All', 'Last 7 days', 'Last 14 days', 'Last 30 days', ...
                'Weekdays', 'Weekend', ...
                'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};

            app.RangeDropDown.Items = rangeItems; % 날짜 드롭다운 선택 목록 설정
            app.RangeDropDown.Value = 'All';      % 날짜 드롭다운 기본값 전체 기간으로 설정

            app.RangeDropDown_2.Items = rangeItems; % 날짜 드롭다운 선택 목록 설정
            app.RangeDropDown_2.Value = 'All';      % 날짜 드롭다운 기본값 전체 기간으로 설정

            app.RangeDropDown_3.Items = rangeItems; % 날짜 드롭다운 선택 목록 설정
            app.RangeDropDown_3.Value = 'All';      % 날짜 드롭다운 기본값 전체 기간으로 설정

            app.T = app.loadLog(); % 복용 기록 파일을 불러오기 또는 생성
            app.updateGraphs();    % 복용 기록 그래프 및 통계 갱신

            app.LCDHoldUntil = 0;    % LCD 메세지 출력 유지 상태 해제
            app.IsConnected = false; % 연결 해제 상태로 갱신
            app.IsRunning = false;   % 실행 중지 상태로 갱신

            app.setControls("disconnected"); % 연결 해제됨 상태로 지정
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if app.IsConnected
                return;
            end

            app.setControls("busy");
            drawnow;

            app.LCDHoldUntil = 0;

            try
                % 아두이노 우노 객체 생성
                app.s1 = arduino('COM5', 'Uno', 'Libraries', 'I2C');

                % 장치 연결 핀 설정
                configurePin(app.s1, app.REED_PIN, 'DigitalInput');
                configurePin(app.s1, app.BUZZER_PIN, 'DigitalOutput');
                configurePin(app.s1, app.RGB_R_PIN, 'DigitalOutput');
                configurePin(app.s1, app.RGB_G_PIN, 'DigitalOutput');
                configurePin(app.s1, app.RGB_B_PIN, 'DigitalOutput');

                % 출력 초기화
                writeDigitalPin(app.s1, app.BUZZER_PIN, 0);
                writeDigitalPin(app.s1, app.RGB_R_PIN, 0);
                writeDigitalPin(app.s1, app.RGB_G_PIN, 0);
                writeDigitalPin(app.s1, app.RGB_B_PIN, 0);

                % LCD 연결 및 초기화
                app.lcd = device(app.s1, ...
                    'I2CAddress', char(app.LCDI2CAdressEditField.Value));

                app.LCDBacklightOn = true;
                app.lcdInit();

                % 모든 연결이 성공한 뒤 연결 상태 변경
                app.IsConnected = true;

                app.showLCDflash('Arduino', 'Connected', 1);
                app.setControls("connected");

            catch ME
                app.IsConnected = false;
                app.IsRunning = false;

                app.lcd = [];
                app.s1 = [];

                app.setControls("disconnected");

                uialert(app.UIFigure, ...
                    ME.message, ...
                    'Connection Error');
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.setControls("busy"); % 작업 중 상태로 지정
            drawnow;                 % 변경 적용

            app.stopApp();        % 모니터링 및 출력 중지
            app.LCDHoldUntil = 0; % LCD 메세지 출력 유지 상태 해제

            app.showLCDflash('Arduino', 'Disconnected', 1); % LCD에 아두이노 연결 해제 메세지 1초간 출력 후 삭제
            app.setLCDBacklight(false);                     % LCD 백라이트 끄기

            app.lcd = []; % LCD 객체 비우기
            app.s1 = [];  % 아두이노 객체 비우기

            app.IsConnected = false; % 아두이노 연결 해제 상태로 갱신
            app.IsRunning = false;   % 실행 중지 상태로 갱신
            app.LCDHoldUntil = 0;    % LCD 메세지 출력 유지 상태 해제

            app.setControls("disconnected"); % 연결 해제됨 상태로 지정
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.IsRunning % 앱 실행 중이면
                return;      % 함수 종료 후 호출 위치로 복귀
            end

            app.setControls("busy"); % 작업 중 상태로 지정
            drawnow;                 % 변경 적용

            app.DoseTime = app.textToDuration(app.DosetimeEditField.Value); % 예정 복용 시간 Duration으로 저장
            app.IsRunning = true;                                           % 앱 실행 상태로 변경
            app.LCDHoldUntil = 0;                                           % LCD 메세지 출력 유지 상태 해제

            app.showLCDhold('Monitoring', 'Started', 1); % LCD에 모니터링 시작 메시지 1초간 출력

            app.TimerObj = timer('ExecutionMode', 'fixedSpacing', ...
                'Period', 0.5, ...
                'TimerFcn', @(~,~) app.checkPillbox());

            start(app.TimerObj); % 타이머 실행

            app.setControls("running"); % 작동 중 상태로 지정
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.setControls("busy"); % 작업 중 상태로 지정
            drawnow;                 % 변경 적용

            app.stopApp();        % 모니터링 및 출력 중지
            app.LCDHoldUntil = 0; % LCD 메세지 출력 유지 상태 해제

            app.showLCDflash('Monitoring', 'Stopped', 1); % LCD에 모니터링 중지 메세지 1초간 출력 후 삭제

            app.setControls("connected"); % 연결됨 상태로 지정
        end

        % Value changed function: RangeDropDown
        function RangeDropDownValueChanged(app, event)
            app.syncRangeDropDowns(app.RangeDropDown.Value); % 선택된 기간 모든 드롭다운에 반영 & 그래프 및 통계 갱신
        end

        % Value changed function: UseLCDCheckBox
        function UseLCDCheckBoxValueChanged(app, event)
            app.LCDHoldUntil = 0; % LCD 메세지 출력 유지 상태 해제

            if app.UseLCDCheckBox.Value               % LCD 사용 체크박스가 체크되어 있으면
                if app.IsRunning                      % 앱 실행 중이면
                    app.showLCDhold('LCD', 'On', 1);  % LCD에 켜짐 메시지 1초간 출력
                else                                  % 앱 실행 중이 아니면
                    app.showLCDflash('LCD', 'On', 1); % LCD에 켜짐 메세지 1초간 출력 후 삭제
                end
            else                                      % LCD 사용 체크박스가 체크되어 있지 않으면
                app.lcdShow('', '');                  % LCD에 공백 메시지 출력
                app.setLCDBacklight(false);           % LCD 백라이트 끄기
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopApp();        % 모니터링 및 출력 중지
            app.LCDHoldUntil = 0; % LCD 메세지 출력 유지 상태 해제

            if app.IsConnected                              % 아두이노가 연결되어 있으면
                writeDigitalPin(app.s1, app.BUZZER_PIN, 0); % 능동 부저 끄기
                writeDigitalPin(app.s1, app.RGB_R_PIN, 0);  % RGB LED 끄기
                writeDigitalPin(app.s1, app.RGB_G_PIN, 0);
                writeDigitalPin(app.s1, app.RGB_B_PIN, 0);
            end

            app.IsConnected = false; % 아두이노 연결 해제 상태로 갱신
            app.IsRunning = false;   % 실행 중지 상태로 갱신
            app.lcd = [];            % LCD 객체 비우기
            app.s1 = [];             % 아두이노 객체 비우기

            delete(app.UIFigure); % UIFigure 삭제
        end

        % Value changed function: DosetimeEditField
        function DosetimeEditFieldValueChanged(app, event)
            app.DoseTime = app.textToDuration(app.DosetimeEditField.Value); % 변경된 예정 복용 시간 즉시 저장
            app.LCDHoldUntil = 0;                                           % LCD 임시 메시지 유지 상태 해제

            if app.IsConnected && app.IsRunning && app.UseLCDCheckBox.Value   % 연결 및 실행 중이면
                app.showLCDhold('Dose time', app.DosetimeEditField.Value, 1); % 변경된 복용 시간 LCD에 1초간 표시
            end
        end

        % Value changed function: RangeDropDown_2
        function RangeDropDown_2ValueChanged(app, event)
            app.syncRangeDropDowns(app.RangeDropDown_2.Value); % 선택된 기간 모든 드롭다운에 반영 & 그래프 및 통계 갱신
        end

        % Value changed function: RangeDropDown_3
        function RangeDropDown_3ValueChanged(app, event)
            app.syncRangeDropDowns(app.RangeDropDown_3.Value); % 선택된 기간 모든 드롭다운에 반영 & 그래프 및 통계 갱신
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.ArduinoPanel = uipanel(app.GeneralTab);
            app.ArduinoPanel.Title = 'Arduino Panel';
            app.ConnectButton = uibutton(app.ArduinoPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ArduinoPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.MonitoringPanel = uipanel(app.GeneralTab);
            app.MonitoringPanel.Title = 'Monitoring Panel';
            app.StartButton = uibutton(app.MonitoringPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MonitoringPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.OptionPanel = uipanel(app.GeneralTab);
            app.OptionPanel.Title = 'Option Panel';
            app.UseLCDCheckBox = uicheckbox(app.OptionPanel);
            app.UseLCDCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseLCDCheckBoxValueChanged, true);
            app.UseLCDCheckBox.Text = 'Use LCD';
            app.UsebuzzerCheckBox = uicheckbox(app.OptionPanel);
            app.UsebuzzerCheckBox.Text = 'Use buzzer';
            app.UseLEDCheckBox = uicheckbox(app.OptionPanel);
            app.UseLEDCheckBox.Text = 'Use LED';
            app.GeneralSettingLabel = uilabel(app.GeneralTab);
            app.GeneralSettingLabel.Text = 'General Setting';
            app.LCDI2CAdressEditFieldLabel = uilabel(app.GeneralTab);
            app.LCDI2CAdressEditFieldLabel.Text = 'LCD I2C Adress';
            app.LCDI2CAdressEditField = uieditfield(app.GeneralTab, 'text');
            app.LCDI2CAdressEditField.Editable = 'off';
            app.PreferenceTab = uitab(app.TabGroup);
            app.PreferenceTab.Title = 'Preference';
            app.SettingPanel_2 = uipanel(app.PreferenceTab);
            app.SettingPanel_2.Title = 'Setting Panel';
            app.TimeallowanceminLabel = uilabel(app.SettingPanel_2);
            app.TimeallowanceminLabel.Text = 'Time allowance (min)';
            app.TimeallowanceminEditField = uieditfield(app.SettingPanel_2, 'numeric');
            app.InformationPanel = uipanel(app.PreferenceTab);
            app.InformationPanel.Title = 'Information Panel';
            app.DosetimeEditFieldLabel = uilabel(app.InformationPanel);
            app.DosetimeEditFieldLabel.Text = 'Dose time';
            app.DosetimeEditField = uieditfield(app.InformationPanel, 'text');
            app.DosetimeEditField.ValueChangedFcn = createCallbackFcn(app, @DosetimeEditFieldValueChanged, true);
            app.DayssupplyofpillsEditFieldLabel = uilabel(app.InformationPanel);
            app.DayssupplyofpillsEditFieldLabel.Text = 'Day''s supply of pills';
            app.DayssupplyofpillsEditField = uieditfield(app.InformationPanel, 'numeric');
            app.PreferenceSettingLabel = uilabel(app.PreferenceTab);
            app.PreferenceSettingLabel.Text = 'Preference Setting';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.TabGroup2 = uitabgroup(app.DataTab);
            app.ActualdosetimeTab = uitab(app.TabGroup2);
            app.ActualdosetimeTab.Title = 'Actual dose time';
            app.ActualDoseTimeAxes = uiaxes(app.ActualdosetimeTab);
            title(app.ActualDoseTimeAxes, 'Actual Dose Time')
            xlabel(app.ActualDoseTimeAxes, 'Date')
            ylabel(app.ActualDoseTimeAxes, 'Time')
            zlabel(app.ActualDoseTimeAxes, 'Z')
            app.AverageEditField_1 = uieditfield(app.ActualdosetimeTab, 'text');
            app.AverageEditField_1.Editable = 'off';
            app.RangeDropDownLabel = uilabel(app.ActualdosetimeTab);
            app.RangeDropDownLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.ActualdosetimeTab);
            app.RangeDropDown.Items = {'Last 7 days', 'Last 14 days', 'Last 30 days', 'Weekdays', 'Weekend', 'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};
            app.RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @RangeDropDownValueChanged, true);
            app.RangeDropDown.Value = 'Last 7 days';
            app.MaximumEditField_1 = uieditfield(app.ActualdosetimeTab, 'text');
            app.MaximumEditField_1.Editable = 'off';
            app.MinimumEditField = uieditfield(app.ActualdosetimeTab, 'text');
            app.MinimumEditField.Editable = 'off';
            app.MinimumEditFieldLabel = uilabel(app.ActualdosetimeTab);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.AverageEditField_3Label = uilabel(app.ActualdosetimeTab);
            app.AverageEditField_3Label.Text = 'Average';
            app.MaximumEditField_3Label = uilabel(app.ActualdosetimeTab);
            app.MaximumEditField_3Label.Text = 'Maximum';
            app.DosedelaytimeTab = uitab(app.TabGroup2);
            app.DosedelaytimeTab.Title = 'Dose delay time';
            app.DoseDelayTimeAxes = uiaxes(app.DosedelaytimeTab);
            title(app.DoseDelayTimeAxes, 'Dose Delay Time')
            xlabel(app.DoseDelayTimeAxes, 'Date')
            ylabel(app.DoseDelayTimeAxes, 'Time')
            zlabel(app.DoseDelayTimeAxes, 'Z')
            app.AverageEditField_2 = uieditfield(app.DosedelaytimeTab, 'text');
            app.AverageEditField_2.Editable = 'off';
            app.AverageEditField_4Label = uilabel(app.DosedelaytimeTab);
            app.AverageEditField_4Label.Text = 'Average';
            app.MaximumEditField_2 = uieditfield(app.DosedelaytimeTab, 'text');
            app.MaximumEditField_2.Editable = 'off';
            app.MaximumEditField_4Label = uilabel(app.DosedelaytimeTab);
            app.MaximumEditField_4Label.Text = 'Maximum';
            app.RangeDropDown_2Label = uilabel(app.DosedelaytimeTab);
            app.RangeDropDown_2Label.Text = 'Range';
            app.RangeDropDown_2 = uidropdown(app.DosedelaytimeTab);
            app.RangeDropDown_2.Items = {'Last 7 days', 'Last 14 days', 'Last 30 days', 'Weekdays', 'Weekend', 'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};
            app.RangeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_2ValueChanged, true);
            app.RangeDropDown_2.Value = 'Last 7 days';
            app.AnalysisTab = uitab(app.TabGroup2);
            app.AnalysisTab.Title = 'Analysis';
            app.DoseRatioAxes = uiaxes(app.AnalysisTab);
            title(app.DoseRatioAxes, 'Dose Ratio')
            app.DoseRatioAxes.XTick = [];
            app.DoseRatioAxes.YTick = [];
            app.RecommenddosetimeEditField = uieditfield(app.AnalysisTab, 'text');
            app.RecommenddosetimeEditField.Editable = 'off';
            app.CorrectdoserateEditField = uieditfield(app.AnalysisTab, 'text');
            app.CorrectdoserateEditField.Editable = 'off';
            app.RecommenddosetimeEditFieldLabel = uilabel(app.AnalysisTab);
            app.RecommenddosetimeEditFieldLabel.Text = {'Recommend'; 'dose time'};
            app.CorrectdoserateEditFieldLabel = uilabel(app.AnalysisTab);
            app.CorrectdoserateEditFieldLabel.Text = 'Correct dose rate';
            app.MaxdelaydayEditField = uieditfield(app.AnalysisTab, 'text');
            app.MaxdelaydayEditField.Editable = 'off';
            app.MaxdelaydayEditFieldLabel = uilabel(app.AnalysisTab);
            app.MaxdelaydayEditFieldLabel.Text = 'Max delay day';
            app.RangeDropDown_3Label = uilabel(app.AnalysisTab);
            app.RangeDropDown_3Label.Text = 'Range';
            app.RangeDropDown_3 = uidropdown(app.AnalysisTab);
            app.RangeDropDown_3.Items = {'Last 7 days', 'Last 14 days', 'Last 30 days', 'Weekdays', 'Weekend', 'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};
            app.RangeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_3ValueChanged, true);
            app.RangeDropDown_3.Value = 'Last 7 days';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smart_pillbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end